classdef Receiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SelectFileDropDown     matlab.ui.control.DropDown
        SelectFileLabel        matlab.ui.control.Label
        ReadFileButton         matlab.ui.control.Button
        WriterButton           matlab.ui.control.Button
        SecondsField           matlab.ui.control.NumericEditField
        EnterSecondsLabel      matlab.ui.control.Label
        FileName               matlab.ui.control.EditField
        EnterFileNameLabel     matlab.ui.control.Label
        ReceivingStatus        matlab.ui.control.Label
        ReceivingStatusLabel   matlab.ui.control.Label
        RetryConnectionButton  matlab.ui.control.Button
        ConnectingStatus       matlab.ui.control.Label
        ConnectionStatusLabel  matlab.ui.control.Label
        RealtimeButton         matlab.ui.control.StateButton
    end


    methods (Access = private)

        %Receives and saves the USRP receiver data
        function startWriterReception(app)
            %Starting Parameters
            center_frequency = 13560000;
            sampling_rate = 2000000;
            global rx;
            global time_scope;
            release(time_scope);
            release(rx);
            time_scope.TimeSpanSource = 'property';

            file_name = app.FileName.Value;
            file_path = append('C:\Users\akamrath2\Documents\USRP_Research_Summer2023\Applications\BasebandFiles\', file_name, '.bb');

            %Create the file writer object
            rxWriter = comm.BasebandFileWriter(file_path, ...
                SampleRate=sampling_rate, ...
                CenterFrequency=center_frequency);

            show(time_scope);
            frame_duration = (rx.SamplesPerFrame)/(sampling_rate);
            time = 0;
            %Gets the first frame of data to set when data collection
            %starts
            data = rx();
            rxWriter(data);
            time_scope(data);
            time = time + frame_duration;

            %Updates buttons and labels
            app.WriterButton.Text = "Receiving for Writer...";
            app.WriterButton.FontColor = [0,1,0];
            app.WriterButton.Enable = false;
            app.ReceivingStatus.Text = "Receiving";
            app.ReceivingStatus.FontColor = [0,1,0];
            drawnow();
            %Writes data to baseband file for given num of seconds
            while time < app.SecondsField.Value
                data = rx();
                rxWriter(data);
                time_scope(data);
                time = time + frame_duration;
            end

            release(rxWriter);
            release(time_scope);
            release(rx);
        end

        %Receives and plots the USRP Receiver data in realtime
        function startRealtimeReception(app)
            global rx;
            global time_scope;
            release(time_scope);
            release(rx);
            time_scope.TimeSpanSource = 'property';
            show(time_scope);

            %One iteration of loop gets 1 frame of data
            while app.RealtimeButton.Value == 1
                data = rx(); %Gets the frame of data
                data = editData(data); %Makes data digital signals
                time_scope(data); %Shows data in time scope

                %Updates buttons and labels
                app.RealtimeButton.Text = "Receiving in Realtime...";
                app.RealtimeButton.FontColor = [0,1,0];
                app.ReceivingStatus.Text = "Receiving";
                app.ReceivingStatus.FontColor = [0,1,0];
                drawnow();
            end
            release(time_scope);
            release(rx);
        end

        %Connects to USRP Receiver and tests the connection
        function rx = establishConnection(app)
            %Parameters
            center_frequency = 13560000;
            sampling_rate = 2000000;
            decimation_factor = round(100000000/sampling_rate);

            %Updates Connection Status
            app.ConnectingStatus.Visible = true;
            app.ConnectingStatus.Text = 'Connecting...';
            app.ConnectingStatus.FontColor = [1,1,0];
            drawnow();

            %Connects to USRP Receiver
            rx = comm.SDRuReceiver(...
                Platform = "N200/N210/USRP2", ...
                IPAddress = '192.168.10.2', ...
                OutputDataType = "double", ...
                DecimationFactor = decimation_factor, ...
                Gain = 0, ...
                CenterFrequency = center_frequency, ...
                SamplesPerFrame = 50000);

            %Tests the USRP connection
            try
                rx();
            catch
                app.ConnectingStatus.Text = 'Connection Failed';
                app.ConnectingStatus.FontColor = [1,0,0];
                app.RetryConnectionButton.Enable = true;
                release(rx);
                return;
            end

            %Updates the UI
            app.RealtimeButton.Enable = true;
            app.WriterButton.Enable = true;
            app.ConnectingStatus.Text = "Connection Established";
            app.ConnectingStatus.FontColor = [0,1,0];
            release(rx);
        end

        %Creates the time scope object
        function time_scope = createTimeScope(app)
            %Parameters
            sampling_rate = 2000000;

            time_scope = timescope(TimeSpanSource = "auto",...
                TimeSpan = 1,SampleRate = sampling_rate, ...
                Position=[20,200,800,500], ...
                YLimits=[-.6,.6]);
            hide(time_scope);
            release(time_scope);
        end

        function populateFiles(app)
            app.SelectFileDropDown.Items={};
            files = dir("BasebandFiles\");
            for k=1:length(files)
                file_name = files(k).name;
                if ~strcmp(file_name, '..') && ~strcmp(file_name, '.')
                    app.SelectFileDropDown.Items = [app.SelectFileDropDown.Items, file_name];
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global rx;
            global time_scope;
            populateFiles(app);
            rx = establishConnection(app);
            time_scope = createTimeScope(app);

        end

        % Value changed function: RealtimeButton
        function RealtimeButtonValueChanged(app, event)
            value = app.RealtimeButton.Value;
            if value == true
                app.RealtimeButton.Text = 'Starting Reception...';
                app.RealtimeButton.FontColor = [1,1,0];
                app.ReceivingStatus.Text = 'Starting Reception...';
                app.ReceivingStatus.FontColor = [1,1,0];
                drawnow();
                startRealtimeReception(app);
            else
                app.RealtimeButton.Text = "Start Realtime Reception";
                app.RealtimeButton.FontColor = [0,0,0];
                app.ReceivingStatus.Text = "Not Receiving";
                app.ReceivingStatus.FontColor = [1,0,0];
            end
        end

        % Button pushed function: RetryConnectionButton
        function RetryConnectionButtonPushed(app, event)
            app.RetryConnectionButton.Enable = false;
            establishConnection(app);
        end

        % Button pushed function: WriterButton
        function WriterButtonPushed(app, event)
            app.WriterButton.Text = 'Starting Reception...';
            app.WriterButton.FontColor = [1,1,0];
            app.ReceivingStatus.Text = 'Starting Reception...';
            app.ReceivingStatus.FontColor = [1,1,0];
            drawnow();
            startWriterReception(app);

            %Updates Buttons and Labels
            app.WriterButton.Text = "Start File Writer Reception";
            app.WriterButton.FontColor = [0,0,0];
            app.WriterButton.Enable = true;
            app.ReceivingStatus.Text = "Not Receiving";
            app.ReceivingStatus.FontColor = [1,0,0];
            populateFiles(app);
            drawnow();

        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            global time_scope
            release(time_scope);
            time_scope.TimeSpanSource = 'auto';
            show(time_scope);
            file_name = app.SelectFileDropDown.Value;
            file_path = append('C:\Users\akamrath2\Documents\USRP_Research_Summer2023\Applications\BasebandFiles\', file_name);

            reader = comm.BasebandFileReader(file_path, SamplesPerFrame=inf);

            data = reader();
            data = editData(data);
            time_scope(data);

            release(time_scope);
            release(reader);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RealtimeButton = uibutton(app.UIFigure, 'state');
            app.RealtimeButton.ValueChangedFcn = createCallbackFcn(app, @RealtimeButtonValueChanged, true);
            app.RealtimeButton.Enable = 'off';
            app.RealtimeButton.Text = 'Start Realtime Reception';
            app.ConnectionStatusLabel = uilabel(app.UIFigure);
            app.ConnectionStatusLabel.Text = 'Connection Status: ';
            app.ConnectingStatus = uilabel(app.UIFigure);
            app.ConnectingStatus.Visible = 'off';
            app.ConnectingStatus.Text = 'Connecting...';
            app.RetryConnectionButton = uibutton(app.UIFigure, 'push');
            app.RetryConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @RetryConnectionButtonPushed, true);
            app.RetryConnectionButton.Enable = 'off';
            app.RetryConnectionButton.Text = 'Retry Connection';
            app.ReceivingStatusLabel = uilabel(app.UIFigure);
            app.ReceivingStatusLabel.Text = 'Receiving Status:';
            app.ReceivingStatus = uilabel(app.UIFigure);
            app.ReceivingStatus.Text = 'Not Receiving';
            app.EnterFileNameLabel = uilabel(app.UIFigure);
            app.EnterFileNameLabel.Text = 'Enter File Name:';
            app.FileName = uieditfield(app.UIFigure, 'text');
            app.EnterSecondsLabel = uilabel(app.UIFigure);
            app.EnterSecondsLabel.Text = 'Enter Seconds:';
            app.SecondsField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsField.Limits = [1 Inf];
            app.SecondsField.Value = 10;
            app.WriterButton = uibutton(app.UIFigure, 'push');
            app.WriterButton.ButtonPushedFcn = createCallbackFcn(app, @WriterButtonPushed, true);
            app.WriterButton.Enable = 'off';
            app.WriterButton.Text = 'Start File Writer Reception';
            app.ReadFileButton = uibutton(app.UIFigure, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.SelectFileLabel = uilabel(app.UIFigure);
            app.SelectFileLabel.Text = 'Select File: ';
            app.SelectFileDropDown = uidropdown(app.UIFigure);
            app.SelectFileDropDown.Items = {};
            app.SelectFileDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Receiver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end